classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FlowrateQEditFieldLabel     matlab.ui.control.Label
        FlowrateQEditField          matlab.ui.control.NumericEditField
        DisplayResult               matlab.ui.control.Table
        userGuideLabel              matlab.ui.control.Label
        ViscosityLabel              matlab.ui.control.Label
        ViscosityDropDown           matlab.ui.control.DropDown
        testLabel                   matlab.ui.control.Label
        PressurePLabel              matlab.ui.control.Label
        PressurePDropDown           matlab.ui.control.DropDown
        RPMDropDownLabel            matlab.ui.control.Label
        RPMDropDown                 matlab.ui.control.DropDown
        Label                       matlab.ui.control.Label
        FlowrateVariationEditField  matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        data = load('data_sheet_all_mod.mat');
        currentFlowrateData 
        currentViscocityData
        currentPrassureData
        flowrate
        guideStr
    end

    methods (Access = private)
        % find flowrate
        function results = findFlowrate(app)
            % getting data from data_sheet_1.mat
            result = find(app.data.flowrate == app.flowrate);
            results  = app.data.df_n(result,:);
        end
        
        function results = findViscocity(app,value)
            % getting data from .mat
            result = find(app.currentFlowrateData(:,3)  == value);
            results = app.currentFlowrateData(result,:);
        end
    
        function results = findPressure(app,inp)
           result = find(app.currentViscocityData(:,4) == inp);
           results = app.currentViscocityData(result,:);
        end 
    
        function results = findFinalIndex(app,inp)
            result = find(app.currentViscocityData(:,4) == inp);
            results = app.currentViscocityData(result,:);
        end
    
        function viscocityfunc(app,Data,f)
            app.currentViscocityData = Data;
            
            listData = unique(Data(:,4));
            app.PressurePDropDown.Items = cellstr(num2str(listData))';
            app.PressurePDropDown.Enable = 'on';
            [col , ~] = size(listData);
            
            
                f(app,Data);
                app.testLabel.Text = num2str(col);
          
            
            app.DisplayResult.Data = Data;
            
        end
    
        function pressurefunc(app,Data)
            app.currentPrassureData = Data;
            app.DisplayResult.Data = Data;
            app.testLabel.Text = 'done';
            index = Data(:,5);
            app.userGuideLabel.Text = strcat('Diameter :',{' '},num2str(index(1)));
        end 
       
        function results = plusOrMinus(app,value,variation)
            max =  value + variation;
            min = value - variation;
            
            result = find(min < app.data.df_n(:,1) & max > app.data.df_n(:,1));
            disp(app.data.df_n(result,:));
            results = result;
            
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DisplayResult.Visible = 'off';
            app.testLabel.Visible = 'off';
            % disable
            app.ViscosityDropDown.Enable = 'off';
            app.PressurePDropDown.Enable = 'off';
            
            
            % defining user to do
            app.userGuideLabel.Text = 'add Flow rate to find diameter';
                                              
        end

        % Value changed function: FlowrateQEditField
        function FlowrateQEditFieldValueChanged(app, event)
            value = app.FlowrateQEditField.Value;
            app.flowrate = value;
            
            Data = findFlowrate(app);
            
            app.PressurePDropDown.Items = {['click to select']};
            app.PressurePDropDown.Enable = 'off';
            app.currentPrassureData = [];
            
            app.ViscosityDropDown.Items = {['click to select']};
            app.ViscosityDropDown.Enable = 'off';
            app.currentViscocityData = [];
            
            %app.ViscosityDropDown.Items = 
            if ~isempty(Data)
                % updating current data
                app.currentFlowrateData = Data;
                
                listData = unique(Data(:,3));
                
                viscodd = cellstr(num2str(listData))';
                app.ViscosityDropDown.Items = viscodd;
                app.ViscosityDropDown.Enable = 'on';
                
                app.DisplayResult.Visible = 'on';
                app.DisplayResult.Data = Data;

                app.userGuideLabel.Text = 'select viscocity';
                app.testLabel.Text = num2str(length(Data'));
                
                [col,~] = size(listData);
                
                f = @pressurefunc;
                
                    viscocityfunc(app,Data,f);   
                
            else 
                app.DisplayResult.Visible = 'off';
                app.ViscosityDropDown.Enable = 'off';
                app.PressurePDropDown.Enable = 'off';
                
                app.userGuideLabel.Text = 'No data Found !';
                
            end            
        end

        % Value changed function: ViscosityDropDown
        function ViscosityDropDownValueChanged(app, event)
            value = str2double(app.ViscosityDropDown.Value);
            
            Data = findViscocity(app,value);
            f = @pressurefunc;
            viscocityfunc(app,Data,f);
            
        end

        % Value changed function: PressurePDropDown
        function PressurePDropDownValueChanged(app, event)
            value = str2double(app.PressurePDropDown.Value);
            
            % selecting final index value
            Data = findFinalIndex(app,value);
            
            pressurefunc(app,Data);
                        
        end

        % Callback function
        function okButtonPushed(app, event)

        end

        % Value changed function: FlowrateVariationEditField
        function FlowrateVariationEditFieldValueChanged(app, event)
            value = app.FlowrateVariationEditField.Value;
            plusOrMinus(app,app.flowrate,value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FlowrateQEditFieldLabel = uilabel(app.UIFigure);
            app.FlowrateQEditFieldLabel.Text = 'Flow rate - Q';
            app.FlowrateQEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlowrateQEditField.ValueChangedFcn = createCallbackFcn(app, @FlowrateQEditFieldValueChanged, true);
            app.DisplayResult = uitable(app.UIFigure);
            app.DisplayResult.ColumnName = {'m3/hr - Q'; '        kW - P'; 'Viscosity'; 'Pressure - bar'; 'Diameter'; 'Pitch'};
            app.DisplayResult.RowName = {};
            app.userGuideLabel = uilabel(app.UIFigure);
            app.userGuideLabel.Text = 'userGuide';
            app.ViscosityLabel = uilabel(app.UIFigure);
            app.ViscosityLabel.Text = 'Viscosity - μ ';
            app.ViscosityDropDown = uidropdown(app.UIFigure);
            app.ViscosityDropDown.Items = {'click to select', 'Option 2', 'Option 3', 'Option 4'};
            app.ViscosityDropDown.ValueChangedFcn = createCallbackFcn(app, @ViscosityDropDownValueChanged, true);
            app.ViscosityDropDown.Value = 'click to select';
            app.testLabel = uilabel(app.UIFigure);
            app.testLabel.Text = 'test';
            app.PressurePLabel = uilabel(app.UIFigure);
            app.PressurePLabel.Text = 'Pressure - P ';
            app.PressurePDropDown = uidropdown(app.UIFigure);
            app.PressurePDropDown.Items = {'click to select', 'Option 2', 'Option 3'};
            app.PressurePDropDown.ValueChangedFcn = createCallbackFcn(app, @PressurePDropDownValueChanged, true);
            app.PressurePDropDown.Value = 'click to select';
            app.RPMDropDownLabel = uilabel(app.UIFigure);
            app.RPMDropDownLabel.Text = 'RPM            ';
            app.RPMDropDown = uidropdown(app.UIFigure);
            app.RPMDropDown.Items = {'950'};
            app.RPMDropDown.Value = '950';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '±';
            app.FlowrateVariationEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlowrateVariationEditField.ValueChangedFcn = createCallbackFcn(app, @FlowrateVariationEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end